classdef plothyperspec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        hyperspec    matlab.ui.Figure
        Slider       matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        image
        lambda
        suffix
        mode
        maxvalue
        minvalue
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, image, mode)

            if ~exist('mode','var')
                mode = 'linear';
            end

            % plot hyperspec image
            if isstruct(image)
                [~,idx] = min(abs(image.lambda-555));
                if isempty(idx)
                    idx = 1;
                end
                imagesc(app.UIAxes,image.image(:,:,idx))
                lambda = image.lambda;
                suffix = ' nm';
                maxvalue = max(image.image(:));
                minvalue = min(image.image(image.image~=0));
                try
                    title(app.UIAxes,image.name);
                catch
                end
            else
                idx = round(size(image,3)/2);
                imagesc(app.UIAxes,image(:,:,idx))
                lambda = 1:size(image,3);
                suffix = '';
                maxvalue = max(image(:));
                minvalue = min(image(image~=0));
                title(app.UIAxes,'')
            end
            %set(app.UIAxes,'DataAspectRatio',[1 1 1])
            axis(app.UIAxes,'auto')
            axis(app.UIAxes,'equal')
            axis(app.UIAxes,'off')
            set(app.UIAxes,'ColorScale',mode)
            c = colorbar(app.UIAxes);
            if strcmp(mode,'log')
                caxis(app.UIAxes,[minvalue maxvalue])
            else
                %caxis(app.UIAxes,[0 maxvalue])
            end

            t = text(app.UIAxes,size(image,1)/20,size(image,2)/20,['\lambda = ',num2str(lambda(idx)),suffix]);
            t.FontName = 'Cambria Math';
            t.Color = [1 1 1];
            c.Label.String = 'L_e in cd m^{-2} nm^{-1}';


            % slider steps
            x = 1:numel(lambda)-1;
            x = x(~(rem(numel(lambda)-1, x)));
            n = (numel(lambda)-1)./x;
            [~,ind] = find(n<=8 & n>=4);
            try
                ind = ind(1);
            catch
            end
            if isempty(ind)
                inc = round(numel(lambda)/8);
            else
                inc = x(ind);
            end
            xt = 1:inc:numel(lambda);

            % set up slider
            app.Slider.Limits = [1 numel(lambda)];%[lambda(1) lambda(end)];
            app.Slider.MajorTicks = xt;
            app.Slider.MajorTickLabels = cellfun(@num2str,num2cell(lambda(xt)),'UniformOutput',false);
            app.Slider.Value = idx;

            % save stuff
            app.image = image;
            app.lambda = lambda;
            app.suffix = suffix;
            app.mode = mode;
            app.maxvalue = maxvalue;
            app.minvalue = minvalue;


        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            idx = round(event.Value);

            % plot hyperspec image
            if isstruct(app.image)
                imagesc(app.UIAxes,app.image.image(:,:,idx))
                t = text(app.UIAxes,size(app.image.image,1)/20,size(app.image.image,2)/20,['\lambda = ',num2str(app.lambda(idx)),app.suffix]);
                try
                    title(app.UIAxes,app.image.name);
                catch
                end
            else
                imagesc(app.UIAxes,app.image(:,:,idx))
                t = text(app.UIAxes,size(app.image,1)/20,size(app.image,2)/20,['\lambda = ',num2str(app.lambda(idx)),app.suffix]);
                title(app.UIAxes,'')
            end
            % image and colour
            axis(app.UIAxes,'equal')
            axis(app.UIAxes,'off')
            set(app.UIAxes,'ColorScale',app.mode)
            c = colorbar(app.UIAxes);
            c.Label.String = 'L_e in cd m^{-2} nm^{-1}';
            if strcmp(app.mode,'log')
                caxis(app.UIAxes,[app.minvalue app.maxvalue])
            else
                caxis(app.UIAxes,[0 app.maxvalue])
            end


            % font
            t.FontName = 'Cambria Math';
            t.Color = [1 1 1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.hyperspec = uifigure('Visible', 'off');
            app.hyperspec.Name = 'hyperspectral image viewer';
            app.UIAxes = uiaxes(app.hyperspec);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'ax';
            app.SliderLabel = uilabel(app.hyperspec);
            app.SliderLabel.Tag = 'lamsym';
            app.SliderLabel.Text = 'λ';
            app.Slider = uislider(app.hyperspec);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Tag = 'sl';
            % Show the figure after all components are created
            app.hyperspec.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plothyperspec(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.hyperspec)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.hyperspec)
        end
    end
end